classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        HighestLevelofEducationDropDownLabel  matlab.ui.control.Label
        HighestLevelofEducationDropDown  matlab.ui.control.DropDown
        EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel  matlab.ui.control.Label
        SearchButton                matlab.ui.control.Button
        TestBoxTextAreaLabel        matlab.ui.control.Label
        TestBoxTextArea             matlab.ui.control.TextArea
        SalaryBracketDropDownLabel  matlab.ui.control.Label
        SalaryBracketDropDown       matlab.ui.control.DropDown
        InterestsLabel              matlab.ui.control.Label
        BusinessCheckBox            matlab.ui.control.CheckBox
        SalesCheckBox               matlab.ui.control.CheckBox
        LawCheckBox                 matlab.ui.control.CheckBox
        EducationServiceCheckBox    matlab.ui.control.CheckBox
        HealthcareCheckBox          matlab.ui.control.CheckBox
        FoodPersonalCareCheckBox    matlab.ui.control.CheckBox
        ProtectiveServicesCheckBox  matlab.ui.control.CheckBox
        EngineeringMathComputersCheckBox  matlab.ui.control.CheckBox
        FarmingFishingForestingCheckBox  matlab.ui.control.CheckBox
        ConstructionMaintenanceManufacturingCheckBox  matlab.ui.control.CheckBox
        ArtsEntertainmentSportsetcCheckBox  matlab.ui.control.CheckBox
        MilitaryCheckBox            matlab.ui.control.CheckBox
        Tab2                        matlab.ui.container.Tab
    end


    properties (Access = public)
        data = csv_processing(); % data from csv file
        subset_data; % will hold data with criteria applied (OR WILL FAIL)
        edu_level = 8; % a code (1 - 8) describing level of education
        interests = []; % vector of ints that code for interests
        salary_bracket = [15000 30000]; % min and max salary
        key1 = ""; % words for user search function 
        key2 = "";
        key3 = "";
    end


    methods (Access = private)

        % Value changed function: HighestLevelofEducationDropDown
        function HighestLevelofEducationDropDownValueChanged(app, event)
            value = app.HighestLevelofEducationDropDown.Value;
            if (strcmp(value, "No Formal Education"))
                app.edu_level = 8;
            elseif (strcmp(value, "High School Diploma or Equivalent"))
                app.edu_level = 7;
            elseif (strcmp(value, "Some College, No Degree"))
                app.edu_level = 6;
            elseif (strcmp(value, "Associate's Degree"))
                app.edu_level = 5; 
            elseif (strcmp(value, "Postsecondary Nondegree Award"))
                app.edu_level = 4;
            elseif (strcmp(value, "Bachelor's Degree"))
                app.edu_level = 3;
            elseif (strcmp(value, "Master's Degree"))
                app.edu_level = 2;
            elseif (strcmp(value, "Doctoral/Professional Degree"))
                app.edu_level = 1;
            end
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.subset_data = criteria_search(app.data, app.edu_level, app.salary_bracket(1), app.salary_bracket(2), app.interests);
            titles = find_titles(app.subset_data); 
            app.TestBoxTextArea.Value = titles;
            %app.TestBoxTextArea.Value = {num2str(app.edu_level), num2str(app.salary_bracket(1)), num2str(app.interests)};
        end

        % Value changed function: SalaryBracketDropDown
        function SalaryBracketDropDownValueChanged(app, event)
            value = app.SalaryBracketDropDown.Value;
            if (strcmp(value, "$15k-30k"))
                app.salary_bracket = [15000 30000];
            elseif (strcmp(value, "$30k-50k"))
                app.salary_bracket = [30000 50000];
            elseif (strcmp(value, "$50k-100k"))
                app.salary_bracket = [50000 100000];
            elseif (strcmp(value, "$100k-150k"))
                app.salary_bracket = [100000 150000];
            elseif (strcmp(value, "$150k+"))
                app.salary_bracket = [150000 100000000];
            end
        end

        % Value changed function: BusinessCheckBox
        function BusinessCheckBoxValueChanged(app, event)
            value = app.BusinessCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 1;
            end
        end

        % Value changed function: SalesCheckBox
        function SalesCheckBoxValueChanged(app, event)
            value = app.SalesCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 2;
            end
        end

        % Value changed function: LawCheckBox
        function LawCheckBoxValueChanged(app, event)
            value = app.LawCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 3;
            end
        end

        % Value changed function: MilitaryCheckBox
        function MilitaryCheckBoxValueChanged(app, event)
            value = app.MilitaryCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 4;
            end
        end

        % Value changed function: EducationServiceCheckBox
        function EducationServiceCheckBoxValueChanged(app, event)
            value = app.EducationServiceCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 5;
            end
        end

        % Value changed function: HealthcareCheckBox
        function HealthcareCheckBoxValueChanged(app, event)
            value = app.HealthcareCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 6;
            end
        end

        % Value changed function: FoodPersonalCareCheckBox
        function FoodPersonalCareCheckBoxValueChanged(app, event)
            value = app.FoodPersonalCareCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 7;
            end
        end

        % Value changed function: ProtectiveServicesCheckBox
        function ProtectiveServicesCheckBoxValueChanged(app, event)
            value = app.ProtectiveServicesCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 8;
            end
        end

        % Value changed function: EngineeringMathComputersCheckBox
        function EngineeringMathComputersCheckBoxValueChanged(app, event)
            value = app.EngineeringMathComputersCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 9;
            end
        end

        % Value changed function: FarmingFishingForestingCheckBox
        function FarmingFishingForestingCheckBoxValueChanged(app, event)
            value = app.FarmingFishingForestingCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 10;
            end
        end

        % Value changed function: 
        % ConstructionMaintenanceManufacturingCheckBox
        function ConstructionMaintenanceManufacturingCheckBoxValueChanged(app, event)
            value = app.ConstructionMaintenanceManufacturingCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 11;
            end
        end

        % Value changed function: ArtsEntertainmentSportsetcCheckBox
        function ArtsEntertainmentSportsetcCheckBoxValueChanged(app, event)
            value = app.ArtsEntertainmentSportsetcCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 12;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.HighestLevelofEducationDropDownLabel = uilabel(app.Tab);
            app.HighestLevelofEducationDropDownLabel.Text = 'Highest Level of Education';
            app.HighestLevelofEducationDropDown = uidropdown(app.Tab);
            app.HighestLevelofEducationDropDown.Items = {'No Formal Education', 'High School Diploma or Equivalent', 'Some College, No Degree', 'Associate''s Degree', 'Postsecondary Nondegree Award', 'Bachelor''s Degree', 'Master''s Degree', 'Doctoral/Professional Degree'};
            app.HighestLevelofEducationDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestLevelofEducationDropDownValueChanged, true);
            app.HighestLevelofEducationDropDown.Value = 'No Formal Education';
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel = uilabel(app.Tab);
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel.Text = 'Enter your information and we''ll let you know what jobs would suit you!';
            app.SearchButton = uibutton(app.Tab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.TestBoxTextAreaLabel = uilabel(app.Tab);
            app.TestBoxTextAreaLabel.Text = 'Test Box';
            app.TestBoxTextArea = uitextarea(app.Tab);
            app.SalaryBracketDropDownLabel = uilabel(app.Tab);
            app.SalaryBracketDropDownLabel.Text = 'Salary Bracket';
            app.SalaryBracketDropDown = uidropdown(app.Tab);
            app.SalaryBracketDropDown.Items = {'$15-30k', '$30k-50k', '$50k-100k', '$100k-150k', '$150k+'};
            app.SalaryBracketDropDown.ValueChangedFcn = createCallbackFcn(app, @SalaryBracketDropDownValueChanged, true);
            app.SalaryBracketDropDown.Value = '$15-30k';
            app.InterestsLabel = uilabel(app.Tab);
            app.InterestsLabel.Text = 'Interests';
            app.BusinessCheckBox = uicheckbox(app.Tab);
            app.BusinessCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusinessCheckBoxValueChanged, true);
            app.BusinessCheckBox.Text = 'Business';
            app.SalesCheckBox = uicheckbox(app.Tab);
            app.SalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SalesCheckBoxValueChanged, true);
            app.SalesCheckBox.Text = 'Sales';
            app.LawCheckBox = uicheckbox(app.Tab);
            app.LawCheckBox.ValueChangedFcn = createCallbackFcn(app, @LawCheckBoxValueChanged, true);
            app.LawCheckBox.Text = 'Law';
            app.EducationServiceCheckBox = uicheckbox(app.Tab);
            app.EducationServiceCheckBox.ValueChangedFcn = createCallbackFcn(app, @EducationServiceCheckBoxValueChanged, true);
            app.EducationServiceCheckBox.Text = 'Education & Service';
            app.HealthcareCheckBox = uicheckbox(app.Tab);
            app.HealthcareCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthcareCheckBoxValueChanged, true);
            app.HealthcareCheckBox.Text = 'Healthcare';
            app.FoodPersonalCareCheckBox = uicheckbox(app.Tab);
            app.FoodPersonalCareCheckBox.ValueChangedFcn = createCallbackFcn(app, @FoodPersonalCareCheckBoxValueChanged, true);
            app.FoodPersonalCareCheckBox.Text = 'Food & Personal Care';
            app.ProtectiveServicesCheckBox = uicheckbox(app.Tab);
            app.ProtectiveServicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProtectiveServicesCheckBoxValueChanged, true);
            app.ProtectiveServicesCheckBox.Text = 'Protective Services';
            app.EngineeringMathComputersCheckBox = uicheckbox(app.Tab);
            app.EngineeringMathComputersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineeringMathComputersCheckBoxValueChanged, true);
            app.EngineeringMathComputersCheckBox.Text = 'Engineering, Math, Computers';
            app.FarmingFishingForestingCheckBox = uicheckbox(app.Tab);
            app.FarmingFishingForestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FarmingFishingForestingCheckBoxValueChanged, true);
            app.FarmingFishingForestingCheckBox.Text = 'Farming, Fishing, Foresting';
            app.ConstructionMaintenanceManufacturingCheckBox = uicheckbox(app.Tab);
            app.ConstructionMaintenanceManufacturingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstructionMaintenanceManufacturingCheckBoxValueChanged, true);
            app.ConstructionMaintenanceManufacturingCheckBox.Text = 'Construction, Maintenance, Manufacturing';
            app.ArtsEntertainmentSportsetcCheckBox = uicheckbox(app.Tab);
            app.ArtsEntertainmentSportsetcCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArtsEntertainmentSportsetcCheckBoxValueChanged, true);
            app.ArtsEntertainmentSportsetcCheckBox.Text = 'Arts, Entertainment, Sports, etc.';
            app.MilitaryCheckBox = uicheckbox(app.Tab);
            app.MilitaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MilitaryCheckBoxValueChanged, true);
            app.MilitaryCheckBox.Text = 'Military';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end