classdef hw4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        JobSearchTab                   matlab.ui.container.Tab
        EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel  matlab.ui.control.Label
        SalaryBracketButtonGroup       matlab.ui.container.ButtonGroup
        k30kButton                     matlab.ui.control.RadioButton
        k150kButton                    matlab.ui.control.RadioButton
        k50kButton_2                   matlab.ui.control.RadioButton
        kButton                        matlab.ui.control.RadioButton
        k100kButton                    matlab.ui.control.RadioButton
        BusinessCheckBox_2             matlab.ui.control.CheckBox
        InterestsLabel                 matlab.ui.control.Label
        SalesCheckBox                  matlab.ui.control.CheckBox
        LawCheckBox                    matlab.ui.control.CheckBox
        EducationServiceCheckBox       matlab.ui.control.CheckBox
        HealthcareCheckBox_2           matlab.ui.control.CheckBox
        FoodPersonalCareCheckBox       matlab.ui.control.CheckBox
        ProtectiveServicesCheckBox     matlab.ui.control.CheckBox
        SearchButton                   matlab.ui.control.Button
        EngineeringMathComputersCheckBox  matlab.ui.control.CheckBox
        FarmingFishingForestingCheckBox  matlab.ui.control.CheckBox
        ConstructionMaintenanceManufacturingCheckBox  matlab.ui.control.CheckBox
        ArtsEntertainmentSportsetcCheckBox  matlab.ui.control.CheckBox
        MilitaryCheckBox               matlab.ui.control.CheckBox
        HighestLevelofEducationDropDownLabel  matlab.ui.control.Label
        HighestLevelofEducationDropDown  matlab.ui.control.DropDown
        JobInfoTab                     matlab.ui.container.Tab
        SearchbyjobtofindthecredentialsLabel  matlab.ui.control.Label
        EnterakeywordEditFieldLabel    matlab.ui.control.Label
        EnterakeywordEditField         matlab.ui.control.EditField
        SearchButton_3                 matlab.ui.control.Button
        EnterakeywordEditField_2Label  matlab.ui.control.Label
        EnterakeywordEditField_2       matlab.ui.control.EditField
        EnterakeywordEditField_3Label  matlab.ui.control.Label
        EnterakeywordEditField_3       matlab.ui.control.EditField
        Enteruptothree3keywordsLabel   matlab.ui.control.Label
    end


    methods (Access = private)
    
        function data_org = cvs_processing(app)
            data = csv2cell('employment_projections.csv', 'fromfile');
            [r, c] = size(data);
            col_names = cell(1, c);
            for i = 1:c
                col_names{1,i} = data{1,i};
            end
            data_org = cell(r-1,10);
            for j = 2:r
                data_org{j-1,1} = data{j,1};
                data_org{j-1,2} = str2double(data{j,2}(3:4));
                data_org{j-1,3} = str2double(data{j,3});
                data_org{j-1,4} = str2double(data{j,4});
                data_org{j-1,5} = str2double(data{j,6});
                data_org{j-1,6} = str2double(data{j,7});
                data_org{j-1,7} = str2double(data{j,8});
                data_org{j-1,8} = str2double(data{j,10});
                data_org{j-1,9} = str2double(data{j,12});
                data_org{j-1,10} = str2double(data{j,14});
            end
        end
        
        function sub_data = criteria_search(app, data, edu_level, salMax, salMin, interest_vec)
            sub_data = edu_sub(data, edu_level); % by education
            if(sub_data ~= "NO DATA")
                sub_data = salary_sub(sub_data, salMax, salMin); % by salary
                if(sub_data ~= "NO DATA")
                    sub_data = int_sub(sub_data, interest_vec); % by interest
                end      
            end 
            
end
        
                
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = cvs_processing(app);
        end

        % Value changed function: HighestLevelofEducationDropDown
        function HighestLevelofEducationDropDownValueChanged(app, event)
            value = app.HighestLevelofEducationDropDown.Value;
            
        end

        % Value changed function: BusinessCheckBox_2
        function BusinessCheckBox_2ValueChanged(app, event)
            value = app.BusinessCheckBox_2.Value;
            
        end

        % Value changed function: SalesCheckBox
        function SalesCheckBoxValueChanged(app, event)
            value = app.SalesCheckBox.Value;
            
        end

        % Value changed function: LawCheckBox
        function LawCheckBoxValueChanged(app, event)
            value = app.LawCheckBox.Value;
            
        end

        % Value changed function: MilitaryCheckBox
        function MilitaryCheckBoxValueChanged(app, event)
            value = app.MilitaryCheckBox.Value;
            
        end

        % Value changed function: EducationServiceCheckBox
        function EducationServiceCheckBoxValueChanged(app, event)
            value = app.EducationServiceCheckBox.Value;
            
        end

        % Value changed function: HealthcareCheckBox_2
        function HealthcareCheckBox_2ValueChanged(app, event)
            value = app.HealthcareCheckBox_2.Value;
            
        end

        % Value changed function: FoodPersonalCareCheckBox
        function FoodPersonalCareCheckBoxValueChanged(app, event)
            value = app.FoodPersonalCareCheckBox.Value;
            
        end

        % Value changed function: ProtectiveServicesCheckBox
        function ProtectiveServicesCheckBoxValueChanged(app, event)
            value = app.ProtectiveServicesCheckBox.Value;
            
        end

        % Value changed function: EngineeringMathComputersCheckBox
        function EngineeringMathComputersCheckBoxValueChanged(app, event)
            value = app.EngineeringMathComputersCheckBox.Value;
            
        end

        % Value changed function: FarmingFishingForestingCheckBox
        function FarmingFishingForestingCheckBoxValueChanged(app, event)
            value = app.FarmingFishingForestingCheckBox.Value;
            
        end

        % Value changed function: 
        % ConstructionMaintenanceManufacturingCheckBox
        function ConstructionMaintenanceManufacturingCheckBoxValueChanged(app, event)
            value = app.ConstructionMaintenanceManufacturingCheckBox.Value;
            
        end

        % Value changed function: ArtsEntertainmentSportsetcCheckBox
        function ArtsEntertainmentSportsetcCheckBoxValueChanged(app, event)
            value = app.ArtsEntertainmentSportsetcCheckBox.Value;
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            criteria_search(data, edu_level, salMax, salMin, interest_vec);
        end

        % Button pushed function: SearchButton_3
        function SearchButton_3Pushed(app, event)
            
        end

        % Selection changed function: SalaryBracketButtonGroup
        function SalaryBracketButtonGroupSelectionChanged(app, event)
            selectedButton = app.SalaryBracketButtonGroup.SelectedObject;
            
        end

        % Value changed function: EnterakeywordEditField
        function EnterakeywordEditFieldValueChanged(app, event)
            value = app.EnterakeywordEditField.Value;
            
        end

        % Value changed function: EnterakeywordEditField_2
        function EnterakeywordEditField_2ValueChanged(app, event)
            value = app.EnterakeywordEditField_2.Value;
            
        end

        % Value changed function: EnterakeywordEditField_3
        function EnterakeywordEditField_3ValueChanged(app, event)
            value = app.EnterakeywordEditField_3.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JobSearchTab = uitab(app.TabGroup);
            app.JobSearchTab.Title = 'Job Search';
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel = uilabel(app.JobSearchTab);
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel.Text = 'Enter your information and we''ll let you know what jobs would suit you! ';
            app.SalaryBracketButtonGroup = uibuttongroup(app.JobSearchTab);
            app.SalaryBracketButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SalaryBracketButtonGroupSelectionChanged, true);
            app.SalaryBracketButtonGroup.Title = 'Salary Bracket';
            app.k30kButton = uiradiobutton(app.SalaryBracketButtonGroup);
            app.k30kButton.Text = '$15k-30k';
            app.k30kButton.Value = true;
            app.k150kButton = uiradiobutton(app.SalaryBracketButtonGroup);
            app.k150kButton.Text = '$100k-150k';
            app.k50kButton_2 = uiradiobutton(app.SalaryBracketButtonGroup);
            app.k50kButton_2.Text = '$30k-50k';
            app.kButton = uiradiobutton(app.SalaryBracketButtonGroup);
            app.kButton.Text = '$150k+';
            app.k100kButton = uiradiobutton(app.SalaryBracketButtonGroup);
            app.k100kButton.Text = '$50k-100k';
            app.BusinessCheckBox_2 = uicheckbox(app.JobSearchTab);
            app.BusinessCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @BusinessCheckBox_2ValueChanged, true);
            app.BusinessCheckBox_2.Text = 'Business';
            app.InterestsLabel = uilabel(app.JobSearchTab);
            app.InterestsLabel.Text = 'Interests';
            app.SalesCheckBox = uicheckbox(app.JobSearchTab);
            app.SalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SalesCheckBoxValueChanged, true);
            app.SalesCheckBox.Text = 'Sales';
            app.LawCheckBox = uicheckbox(app.JobSearchTab);
            app.LawCheckBox.ValueChangedFcn = createCallbackFcn(app, @LawCheckBoxValueChanged, true);
            app.LawCheckBox.Text = 'Law';
            app.EducationServiceCheckBox = uicheckbox(app.JobSearchTab);
            app.EducationServiceCheckBox.ValueChangedFcn = createCallbackFcn(app, @EducationServiceCheckBoxValueChanged, true);
            app.EducationServiceCheckBox.Text = 'Education & Service';
            app.HealthcareCheckBox_2 = uicheckbox(app.JobSearchTab);
            app.HealthcareCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HealthcareCheckBox_2ValueChanged, true);
            app.HealthcareCheckBox_2.Text = 'Healthcare';
            app.FoodPersonalCareCheckBox = uicheckbox(app.JobSearchTab);
            app.FoodPersonalCareCheckBox.ValueChangedFcn = createCallbackFcn(app, @FoodPersonalCareCheckBoxValueChanged, true);
            app.FoodPersonalCareCheckBox.Text = 'Food & Personal Care';
            app.ProtectiveServicesCheckBox = uicheckbox(app.JobSearchTab);
            app.ProtectiveServicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProtectiveServicesCheckBoxValueChanged, true);
            app.ProtectiveServicesCheckBox.Text = 'Protective Services';
            app.SearchButton = uibutton(app.JobSearchTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search!';
            app.EngineeringMathComputersCheckBox = uicheckbox(app.JobSearchTab);
            app.EngineeringMathComputersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineeringMathComputersCheckBoxValueChanged, true);
            app.EngineeringMathComputersCheckBox.Text = 'Engineering, Math, Computers';
            app.FarmingFishingForestingCheckBox = uicheckbox(app.JobSearchTab);
            app.FarmingFishingForestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FarmingFishingForestingCheckBoxValueChanged, true);
            app.FarmingFishingForestingCheckBox.Text = 'Farming, Fishing, Foresting';
            app.ConstructionMaintenanceManufacturingCheckBox = uicheckbox(app.JobSearchTab);
            app.ConstructionMaintenanceManufacturingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstructionMaintenanceManufacturingCheckBoxValueChanged, true);
            app.ConstructionMaintenanceManufacturingCheckBox.Text = 'Construction, Maintenance, Manufacturing';
            app.ArtsEntertainmentSportsetcCheckBox = uicheckbox(app.JobSearchTab);
            app.ArtsEntertainmentSportsetcCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArtsEntertainmentSportsetcCheckBoxValueChanged, true);
            app.ArtsEntertainmentSportsetcCheckBox.Text = 'Arts, Entertainment, Sports, etc.';
            app.MilitaryCheckBox = uicheckbox(app.JobSearchTab);
            app.MilitaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MilitaryCheckBoxValueChanged, true);
            app.MilitaryCheckBox.Text = 'Military';
            app.HighestLevelofEducationDropDownLabel = uilabel(app.JobSearchTab);
            app.HighestLevelofEducationDropDownLabel.Text = 'Highest Level of Education';
            app.HighestLevelofEducationDropDown = uidropdown(app.JobSearchTab);
            app.HighestLevelofEducationDropDown.Items = {'No Formal Education', 'High School Diploma or Equivalent', 'Some College', 'Postsecondary Nondegree Award', 'Associate''s Degree', 'Bachelor''s Degree', 'Master''s Degree', 'Doctoral/Proessional Degree'};
            app.HighestLevelofEducationDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestLevelofEducationDropDownValueChanged, true);
            app.HighestLevelofEducationDropDown.Value = 'No Formal Education';
            app.JobInfoTab = uitab(app.TabGroup);
            app.JobInfoTab.Title = 'Job Info';
            app.SearchbyjobtofindthecredentialsLabel = uilabel(app.JobInfoTab);
            app.SearchbyjobtofindthecredentialsLabel.Text = 'Search by job to find the credentials!';
            app.EnterakeywordEditFieldLabel = uilabel(app.JobInfoTab);
            app.EnterakeywordEditFieldLabel.Text = 'Enter a keyword';
            app.EnterakeywordEditField = uieditfield(app.JobInfoTab, 'text');
            app.EnterakeywordEditField.ValueChangedFcn = createCallbackFcn(app, @EnterakeywordEditFieldValueChanged, true);
            app.SearchButton_3 = uibutton(app.JobInfoTab, 'push');
            app.SearchButton_3.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_3Pushed, true);
            app.SearchButton_3.Text = 'Search!';
            app.EnterakeywordEditField_2Label = uilabel(app.JobInfoTab);
            app.EnterakeywordEditField_2Label.Text = 'Enter a keyword';
            app.EnterakeywordEditField_2 = uieditfield(app.JobInfoTab, 'text');
            app.EnterakeywordEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterakeywordEditField_2ValueChanged, true);
            app.EnterakeywordEditField_3Label = uilabel(app.JobInfoTab);
            app.EnterakeywordEditField_3Label.Text = 'Enter a keyword';
            app.EnterakeywordEditField_3 = uieditfield(app.JobInfoTab, 'text');
            app.EnterakeywordEditField_3.ValueChangedFcn = createCallbackFcn(app, @EnterakeywordEditField_3ValueChanged, true);
            app.Enteruptothree3keywordsLabel = uilabel(app.JobInfoTab);
            app.Enteruptothree3keywordsLabel.Text = 'Enter up to three (3) keywords';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hw4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end